classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        CloseApplicationButton          matlab.ui.control.Button
        AIPanel                         matlab.ui.container.Panel
        ECEITSPanel                     matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        IntelligentTutoringSystemLabel  matlab.ui.control.Label
        ElectricalandComputerEngineeringLabel  matlab.ui.control.Label
        ToolbarPanel                    matlab.ui.container.Panel
    end

    
    properties (Access = public)

        % AIAppHandle contains the AskAI App
        % SubjectAppHandle contains the Subject App this being Concept Explorer, System Test, etc.   
        % ToolbarAppHandle contains the Toolbar App this being the toolbar(s) needed to compliment the Subject App.
        
        AIAppHandle
        
        SubjectAppHandle
        ToolbarAppHandle

        % appLoaded is the number indentifier for the Subject App this being 1 for Concept Explorer, 2 for System Test, etc.
        % appPreliminary is a flag indictating the progress in subject selection.

        appLoaded
        appPreliminary = false;

        
    end

    methods (Access = public)


        function handleAppCommunication(app, userInput, toApp)
            
            switch toApp

                case "0"

                    app.handleContentSelection(userInput);

                case "1"

                case "2"

                    app.handleContentSelection(userInput);

                case "3"
                    app.AIAppHandle.postMessage("You selected Fundamentals of Computer Engineering (ECE 241).");

                case "4"
                    app.AIAppHandle.postMessage("You selected Electronic Circuits (ECE 313).");

            end

            function handleContentSelection(app, userInput)


                app.handleAppCommunication()  

            end 


function appHandle = toggleAppEmbedding(app, appConstructor, parentPanel, currentHandle)

    if isempty(currentHandle) || ~isvalid(currentHandle)
        % ===== CREATE CHILD APP =====
        childApp = appConstructor();

        % Hide the UIFigure immediately to avoid flicker
        if isprop(childApp, 'UIFigure')
            childApp.UIFigure.Visible = 'off';
            drawnow;
        end

        % --- GET REAL TOP-LEVEL CONTAINER ---
        topContainer = findobj(childApp.UIFigure.Children, 'Type', 'uigridlayout');
        if isempty(topContainer)
            error("Could not find a valid uigridlayout in the child app.");
        end

        % Ensure single handle
        topContainer = topContainer(1);

        % Embed into your target panel
        topContainer.Parent = parentPanel;

        % No Units/Position needed — gridlayout fills parent automatically

        appHandle = childApp;
        return;
    end

    % ===== DELETE EXISTING CHILD APP =====
    try
        delete(currentHandle);
    catch ME
        warning("Failed to delete child app: %s", ME.message);
    end
    appHandle = [];
end


        function absPos = getAbsolutePosition(app, component)
 
            absPos = component.Position;
            parent = component.Parent;
            while ~isa(parent, 'matlab.ui.Figure')
                absPos(1:2) = absPos(1:2) + parent.Position(1:2);
                parent = parent.Parent;
            end
        end

    function handleSubjectSelection(app, userInput)
        choice = strtrim(lower(userInput));

        switch choice
            case "1"
                app.AIAppHandle.postMessage("You selected Circuit Analysis 1 (ECE 201).");

            case "2"
                app.AIAppHandle.postMessage("You selected Circuit Analysis 2 (ECE 202).");

            case "3"
                app.AIAppHandle.postMessage("You selected Fundamentals of Computer Engineering (ECE 241).");

            case "4"
                app.AIAppHandle.postMessage("You selected Electronic Circuits (ECE 313).");

        % add other subjects as needed           

            otherwise
            app.AIAppHandle.postMessage("Invalid choice, please select 1-4.");
            return;
        end

        % Ask for the feature/app
        app.AIAppHandle.postMessage(...
        "Which app would you like to access?" + newline + ...
        "1) Concept Explorer" + newline + ...
        "2) Dynamic Testing" + newline + ...
        "3) System Design" + newline + ...
        "4) Quiz");

        app.AIAppHandle.waitingForSubject = false;  % switch to feature selection
    
    end

    function handleFeatureSelection(app, userInput)

    app.appLoaded = strtrim(lower(userInput));

    switch choice
        case "1"

            if ~app.userUpdated

            app.AIAppHandle.postMessage("Launching Concept Explorer...");
            app.AIAppHandle.AIHelperHandle.launchConceptExplorer();

            app.appPreliminary = true;

            end

        case "2"

            if ~app.userUpdated

            app.AIAppHandle.postMessage("Launching Dynamic Testing...");
            app.AI.launchDynamicTesting();

            app.appPreliminary = true;

            end

        case "3"

            if ~app.userUpdated

            app.AIAppHandle.postMessage("Launching System Design...");
            app.AI.launchSystemDesign();

            app.appPreliminary = true;

            end

        case "4"
            
            if ~app.userUpdated

            app.AIAppHandle.postMessage("Launching Quiz...");
            
            % Change the toolbar to the quiz toolbar  
            
            app.ToolbarAppHandle = app.toggleAppEmbedding(@quizToolbar, app.ToolbarPanel, app.ToolbarAppHandle);
            
            % Set subject app handle to the quiz app    
            
            app.SubjectAppHandle = quiz();
            app.SubjectAppHandle.setMainApp(app);

            app.appPreliminary = true;

            end

            app.SubjectAppHandle.doQuiz(userInput);

        otherwise
            app.AIAppHandle.postMessage("Invalid choice, please select 1-4.");
    end

    % Reset to subject menu if you want to allow looping
    app.waitingForSubject = true;
    app.postMessage(...
        "1) Circuit Analysis 1 (ECE 201)" + newline + ...
        "2) Circuit Analysis 2 (ECE 202)" + newline + ...
        "3) Fundamentals of Computer Engineering (ECE 241)" + newline + ...
        "4) Electronic Circuits (ECE 313)");
    end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        app.UIFigure.WindowState = 'maximized';

        % Embed AskAi into panel
        app.AIAppHandle = app.toggleAppEmbedding(@AskAi, app.AIPanel, app.AIAppHandle);

        app.AIAppHandle.setMainApp(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
  
        % Do Nothing

        end

        % Button pushed function: CloseApplicationButton
        function CloseApplicationButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ToolbarPanel = uipanel(app.GridLayout);
            app.ECEITSPanel = uipanel(app.GridLayout);
            app.ElectricalandComputerEngineeringLabel = uilabel(app.ECEITSPanel);
            app.ElectricalandComputerEngineeringLabel.Text = 'Electrical and Computer Engineering ';
            app.IntelligentTutoringSystemLabel = uilabel(app.ECEITSPanel);
            app.IntelligentTutoringSystemLabel.Text = 'Intelligent Tutoring System';
            app.Image = uiimage(app.ECEITSPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'odu.png');
            app.AIPanel = uipanel(app.GridLayout);
            app.CloseApplicationButton = uibutton(app.GridLayout, 'push');
            app.CloseApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @CloseApplicationButtonPushed, true);
            app.CloseApplicationButton.Text = 'Close Application';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end